classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        STOPButton     matlab.ui.control.Button
        Button_17      matlab.ui.control.Button
        Button_16      matlab.ui.control.Button
        Button_18      matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_14      matlab.ui.control.Button
        Button_13      matlab.ui.control.Button
        Button_15      matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_11      matlab.ui.control.Button
        Button_10      matlab.ui.control.Button
        Button_12      matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
        ResetButton_2  matlab.ui.control.Button
        ResetButton    matlab.ui.control.Button
        EditField_2    matlab.ui.control.EditField
        EditField      matlab.ui.control.EditField
        Label          matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelUR3 % Where I will put the loaded UR5
        modelKUKA
        modelGripperBase
        Gripper2
        Gripper3
        Gr2vertices
        Gr3vertices
        qz = [0     0       0       0               0       0   0];
        qrKUKA = [0	pi/3   0	    pi/4	    0	    5*pi/12   pi 0 0];
        qrUR3 = [0	-3*pi/4   -pi/4	-pi/2           pi/2	-pi/2   0 0]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = gca;

            hold on
            axis([-2 2 -2 2 -0.01 2 ]);
            view(3)
            % Make, save and plot some robots            
            app.modelUR3 = UR3;
            app.modelUR3.model.base = transl([-0.5,0,0.5]);
            app.modelUR3.model.animate(app.qrUR3);
            drawnow();


%             app.modelKUKA = KUKA;
%             app.modelKUKA.model.base = transl([0.8,0,0.5]);
%             app.modelKUKA.model.animate(app.qrKUKA);
%             drawnow();


%             app.Gripper2=PlaceObject('Gripper2.ply');
%             app.Gripper3=PlaceObject('Gripper3.ply');
% %             
%             app.Gr2vertices = get(app.Gripper2,'Vertices');
%             app.Gr3vertices = get(app.Gripper3,'Vertices');
% %             
%             tr=T(app.modelUR3.model.fkine(app.qrUR3));
%             transformedGr2vertices = [app.Gr2vertices,ones(size(app.Gr2vertices,1),1)] * tr';
%             transformedGr3vertices = [app.Gr3vertices,ones(size(app.Gr3vertices,1),1)] * tr';
%             set(app.Gripper2,'Vertices',transformedGr2vertices(:,1:3));
%             set(app.Gripper3,'Vertices',transformedGr3vertices(:,1:3));


% surf([-4,-4;4,4] ...
% ,[-4,4;-4,4] ...
% ,[0.01,0.01;0.01,0.01] ...
% ,'CData',imread('floor.jpg') ...
% ,'FaceColor','texturemap');
% 


% 
% PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0.25,0,0]);
% 
% % PlaceObject('Moles.ply', [0,0,0.5]);
% PlaceObject('Base.ply', [0,0,0.5]);
% 
% PlaceObject('mole5.ply', [-0.06, 0.06,    0.5]);
% PlaceObject('mole5.ply', [0,    0.06,    0.5]);
% PlaceObject('mole5.ply', [0.06,  0.06,    0.5]);
% 
% PlaceObject('mole5.ply', [-0.06, 0,  0.5]);
% PlaceObject('mole5.ply', [0,    0,  0.5]);
% PlaceObject('mole5.ply', [0.06,  0,  0.5]);
% 
% 
% PlaceObject('mole5.ply', [-0.06, -0.06,  0.5]);
% PlaceObject('mole5.ply', [0,    -0.06,  0.5]);
% PlaceObject('mole5.ply', [0.06,  -0.06,  0.5]);

% PlaceObject('mole2.ply', [0,0,0.5]);
% PlaceObject('mole3.ply', [0,0,0.5]);
% PlaceObject('mole4.ply', [0,0,0.5]);
% PlaceObject('mole5.ply', [0,0,0.5]);
% PlaceObject('mole6.ply', [0,0,0.5]);

% PlaceObject('Hammer.ply', [0,0,0.5]);
% person1=PlaceObject('personMaleConstructionEdited.ply',[0,2.5,0]);
% 
% stopbutt=PlaceObject('emergencyStopButtonEdited.ply',[-0.25,2.2,1.15]);
% 
% gavins=PlaceObject('barrier1.5x0.2x1m.ply',[0,-2,0]);
% number=PlaceObject('barrier1.5x0.2x1m.ply',[-1.5,-2,0]);
% one=PlaceObject('barrier1.5x0.2x1m.ply',[1.5,-2,0]);
% 
% fan=PlaceObject('barrierRotated.ply',[2.25,-1.25,0]);
% woohooo=PlaceObject('barrierRotated.ply',[2.25,0.25,0]);
% barrier6=PlaceObject('barrierRotated.ply',[2.25,1.75,0]);
% 
% barrier7=PlaceObject('barrierRotated.ply',[-2.25,-1.25,0]);
% barrier8=PlaceObject('barrierRotated.ply',[-2.25,0.25,0]);
% barrier9=PlaceObject('barrierRotated.ply',[-2.25,1.75,0]);
% 
% 
% barrier10=PlaceObject('barrier1.5x0.2x1m.ply',[-1.5,2.5,0]);
% barrier11=PlaceObject('barrier1.5x0.2x1m.ply',[1.5,2.5,0]);
% 
% person2=PlaceObject('personMaleOld.ply',[-1.5,3,0]);
% gavin=PlaceObject('personMaleCasual.ply',[-2.8,0,0]);
% sheila=PlaceObject('personFemaleBusiness.ply',[-0.5,-2.5,0]);
% 
% fireEx=PlaceObject('fireExtinguisher.ply',[1.5,2.6,0]);


%             app.modelGripperBase = GripperBase;
%             q=app.modelUR3.model.getpos()
%             app.modelGripperBase.model.base = app.modelUR3.model.fkine(app.qrUR3);
%             app.modelGripperBase.animate(0)
%             drawnow();
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
%             for i = size(ax.Children,1):-1:1
%                 if strcmp(ax.Children(i).Type,'surface')
%                     delete(ax.Children(i));
%                 end
%             end
            
            % Turn off this load button and enable the move button
            app.Button.Enable = true;
            app.Button_3.Enable = true;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Callback function
        function RotRButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(5)>-2*pi
                qNew=[qCurrent(1:4),qCurrent(5)-pi/8,qCurrent(6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Callback function
        function BackButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            posCurrent=T(app.modelUR5.model.fkine(qCurrent));
            posNew=posCurrent*transl(0, 0, -0.2);
            qNew=app.modelUR5.model.ikcon(posNew)

            qmatrix = jtraj(qCurrent,qNew,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Callback function
        function RotLButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(5)<2*pi
                qNew=[qCurrent(1:4),qCurrent(5)+pi/8,qCurrent(6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Callback function
        function ForwardButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            posCurrent=T(app.modelUR5.model.fkine(qCurrent));
            posNew=posCurrent*transl(0, 0, 0.2);
            qNew=app.modelUR5.model.ikcon(posNew)

            qmatrix = jtraj(qCurrent,qNew,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Callback function
        function RotUButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(4)>-2*pi
                qNew=[qCurrent(1:3),qCurrent(4)+pi/8,qCurrent(5:6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Callback function
        function RotDButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(4)>-2*pi
                qNew=[qCurrent(1:3),qCurrent(4)-pi/8,qCurrent(5:6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.figure1);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.GridLayout);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Value = 'WackBot';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.Value = 'ShieldBot';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.Text = 'Reset';
            app.ResetButton_2 = uibutton(app.GridLayout, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.Text = '3';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.Text = '1';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.Text = '2';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.Text = '3';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.Text = '6';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.Text = '4';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.Text = '5';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.Text = '6';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.Text = '7';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.Text = '8';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.Text = '9';
            app.Button_18 = uibutton(app.GridLayout, 'push');
            app.Button_18.Text = '7';
            app.Button_16 = uibutton(app.GridLayout, 'push');
            app.Button_16.Text = '8';
            app.Button_17 = uibutton(app.GridLayout, 'push');
            app.Button_17.Text = '9';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end