classdef Assignment3UI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        UpButton       matlab.ui.control.Button
        DownButton     matlab.ui.control.Button
        LeftButton     matlab.ui.control.Button
        RightButton    matlab.ui.control.Button
        RotUButton     matlab.ui.control.Button
        BackButton     matlab.ui.control.Button
        ForwardButton  matlab.ui.control.Button
        RotLButton     matlab.ui.control.Button
        RotRButton     matlab.ui.control.Button
        RotDButton     matlab.ui.control.Button
        STOPButton     matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelUR5 % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];
        qrUR5 = [0	-pi/4  -pi/4	0	pi/2	0]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = gca;

            % Make, save and plot some robots            
            app.modelUR5 = UR5;
            app.modelUR5.model.base = transl([-0.5,0,0]);
            app.modelUR5.model.animate(app.qrUR5);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.UpButton.Enable = true;
            app.DownButton.Enable = true;
            
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Button pushed function: RotRButton
        function RotRButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(5)>-2*pi
                qNew=[qCurrent(1:4),qCurrent(5)-pi/8,qCurrent(6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            posCurrent=T(app.modelUR5.model.fkine(qCurrent));
            posNew=posCurrent*transl(0, 0, -0.2);
            qNew=app.modelUR5.model.ikcon(posNew)

            qmatrix = jtraj(qCurrent,qNew,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Button pushed function: RotLButton
        function RotLButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(5)<2*pi
                qNew=[qCurrent(1:4),qCurrent(5)+pi/8,qCurrent(6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            posCurrent=T(app.modelUR5.model.fkine(qCurrent));
            posNew=posCurrent*transl(0, 0, 0.2);
            qNew=app.modelUR5.model.ikcon(posNew)

            qmatrix = jtraj(qCurrent,qNew,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Button pushed function: RotUButton
        function RotUButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(4)>-2*pi
                qNew=[qCurrent(1:3),qCurrent(4)+pi/8,qCurrent(5:6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Button pushed function: RotDButton
        function RotDButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(4)>-2*pi
                qNew=[qCurrent(1:3),qCurrent(4)-pi/8,qCurrent(5:6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.figure1);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.STOPButton = uibutton(app.figure1, 'push');
            app.STOPButton.Text = 'STOP';
            app.RotDButton = uibutton(app.figure1, 'push');
            app.RotDButton.ButtonPushedFcn = createCallbackFcn(app, @RotDButtonPushed, true);
            app.RotDButton.Text = 'RotD';
            app.RotRButton = uibutton(app.figure1, 'push');
            app.RotRButton.ButtonPushedFcn = createCallbackFcn(app, @RotRButtonPushed, true);
            app.RotRButton.Text = 'RotR';
            app.RotLButton = uibutton(app.figure1, 'push');
            app.RotLButton.ButtonPushedFcn = createCallbackFcn(app, @RotLButtonPushed, true);
            app.RotLButton.Text = 'RotL';
            app.ForwardButton = uibutton(app.figure1, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.figure1, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.RotUButton = uibutton(app.figure1, 'push');
            app.RotUButton.ButtonPushedFcn = createCallbackFcn(app, @RotUButtonPushed, true);
            app.RotUButton.Text = 'RotU';
            app.RightButton = uibutton(app.figure1, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.figure1, 'push');
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.figure1, 'push');
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.figure1, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment3UI2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end