classdef WackBotMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ViewHitboxButton_2        matlab.ui.control.Button
        ViewHitboxButton          matlab.ui.control.Button
        KUKAJointStateLabel       matlab.ui.control.Label
        UR3JointStateLabel        matlab.ui.control.Label
        StatusShieldBotLabel      matlab.ui.control.Label
        StatusWackBotLabel        matlab.ui.control.Label
        ShieldBotLabel            matlab.ui.control.Label
        WackBotLabel              matlab.ui.control.Label
        ShieldBotScoreBoardLabel  matlab.ui.control.Label
        STOPButton                matlab.ui.control.Button
        Button_R9                 matlab.ui.control.Button
        Button_R8                 matlab.ui.control.Button
        Button_R7                 matlab.ui.control.Button
        Button_L9                 matlab.ui.control.Button
        Button_L8                 matlab.ui.control.Button
        Button_L7                 matlab.ui.control.Button
        Button_R6                 matlab.ui.control.Button
        Button_R5                 matlab.ui.control.Button
        Button_R4                 matlab.ui.control.Button
        Button_L6                 matlab.ui.control.Button
        Button_L5                 matlab.ui.control.Button
        Button_L4                 matlab.ui.control.Button
        Button_R3                 matlab.ui.control.Button
        Button_R2                 matlab.ui.control.Button
        Button_R1                 matlab.ui.control.Button
        Button_L3                 matlab.ui.control.Button
        Button_L2                 matlab.ui.control.Button
        Button_L1                 matlab.ui.control.Button
        ResetShieldBotButton      matlab.ui.control.Button
        ResetWackBotButton        matlab.ui.control.Button
        WackBotScoreBoardLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelUR3 % Where I will put the loaded UR5
        modelKUKA
        mole
        eStop
        warningdialog
        wackBotScore
        shieldBotScore
        wackInput
        shieldInput
        winner
        joy

        qz = [0     0       0       0               0       0   0];
%         KUKAq0 =[-0.01 0 0	pi/3   0	    pi/4	    0	    1.3090   pi 0 0 0]
        KUKAq0 =[-0.5854    0.0009    0.0727    0.7652-0.3491    0.2359    2.1368+0.3491   -3.7208   -0.3004    0.7854         0         0   0]
        UR3q0 = [0.2427	4.3808	4.6426	-1.194985	-4.71235274267090	-1.30105403435293	5.95508023803729e-06	5.95508307285278e-06	-0.0270717610752961]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = gca;
            global reset
            reset=false;

            app.wackBotScore=0;
            app.shieldBotScore=0;

            app.wackInput=0;
            app.shieldInput=0;

            app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];
            app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];

%             app.StatusLabel.Text="Status:";

            app.StatusWackBotLabel.Text="Awaiting Input";
            app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];

            app.StatusShieldBotLabel.Text="Awaiting Input";
            app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];


            hold on
            axis([-2 2 -2 2 -0.01 2 ]);
%             view(3)
            campos([-0.0045  -10.5324    6.3959])
            camtarget([0.1088   -0.0471    0.7224])
            
%             camva(3.5)
            % Make, save and plot some robots            
            app.modelUR3 = ModifiedUR3;
            app.modelUR3.model.base = transl([-0.6,0,0.574]);
            app.modelUR3.model.animate(app.UR3q0);
            drawnow();

            app.modelKUKA = KUKA(transl([1.1,0,0.5]));
%             app.modelKUKA = KUKA;
%             app.modelKUKA.model.base = transl([0.8,0,0.5]);
            app.modelKUKA.model.animate(app.KUKAq0);
            drawnow();
            
            app.eStop=false;
%             app.reset=false;
          

            surf([-4,-4;4,4] ...
            ,[-4,4;-4,4] ...
            ,[0.01,0.01;0.01,0.01] ...
            ,'CData',imread('floor.jpg') ...
            ,'FaceColor','texturemap');
            
            PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0.25,0,0]);
            
            % PlaceObject('Moles.ply', [0,0,0.5]);
            PlaceObject('Base2.ply', [0,0,0.5]);
            
            moleID(1,1)=PlaceObject('MoleNew.ply', [-0.06, 0.06,    0.5]);
            moleID(2,1)=PlaceObject('MoleNew.ply', [0,    0.06,    0.515]);
            moleID(3,1)=PlaceObject('MoleNew.ply', [0.06,  0.06,    0.5]);
            
            moleID(4,1)=PlaceObject('MoleNew.ply', [-0.06, 0,  0.5]);
            moleID(5,1)=PlaceObject('MoleNew.ply', [0,    0,  0.515]);
            moleID(6,1)=PlaceObject('MoleNew.ply', [0.06,  0,  0.5]);
            
            
            moleID(7,1)=PlaceObject('MoleNew.ply', [-0.06, -0.06,  0.515]);
            moleID(8,1)=PlaceObject('MoleNew.ply', [0,    -0.06,  0.5]);
            moleID(9,1)=PlaceObject('MoleNew.ply', [0.06,  -0.06,  0.5]);

            height=(0.574);
            molePos=[-0.06    0.06     height
                0      0.06     height
                0.06    0.06     height
                -0.06     0       height
                0       0       height
                0.06     0       height
                -0.06   -0.06     height
                0     -0.06      height
                0.06   -0.06      height];

            Status=[0;1;0;0;1;0;1;0;0];
            app.mole=table(moleID,Status,molePos);


% 
%             r = ModifiedUR3;

centerPoint = [0 0 0];
% radii = [0.328,0.091,0.123];
radii = [0.128 0.163 0.261;
            0.328,0.091,0.123;
            0.283,0.075,0.971;
            0.0644,0.0768,0.0877;
            0.0644,0.0768,0.0877;
            0.063,0.0696,0.0398;
            0.0614,0.0923,0.126;
            0.197,0.045,0.1
            0,0,0];

[X,Y,Z] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radii(1), radii(2), radii(3));
k = 1;
m = 21;
X = zeros(21,168);
Y = zeros(21,168);
Z = zeros(21,168);

for j = 1:21:168
    [X(1:21,j:m),Y(1:21,j:m),Z(1:21,j:m)] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radii(k,1), radii(k,2), radii(k,3));
    m = m + 21;
end
X = X(:);
Y = Y(:);
Z = Z(:);


b = 1;
n = 422;
for i = 1:8
    app.modelUR3.model.points{i} = [X(b:n), Y(b:n), Z(b:n)];
    warning off
    app.modelUR3.model.faces{i} = delaunay(app.modelUR3.model.points{i});
    warning on;
    b = b + 422;
    n = n + 422;
end

% 
% 
% app.modelUR3.model.plot3d([app.UR3q0]);
% % alpha(0.1)
% axis equal
% camlight


centerPoint = [0 0 0];
radiiKUKA = [ 0.20799, 0.046, 0.058 ];
% radii = [0.5,0.216,1.12;
%             0.217005,0.207952,0.157513;
%             0.135983,0.250555,0.182625;
%             0.136013,0.182614,0.284818;
%             0.136095,0.251215,0.182625;
%             0.136016,0.182597,0.285527;
%             0.135971, 0.234071, 0.167121 ;
%             0.132334, 0.129053,  0.167061;
%             0.104072, 0.104033, 0.030367;
%             0.061356, 0.092336, 0.125051;
%             0.20799, 0.046, 0.058 
%             0.01, 0.01, 0.01];

[X2,Y2,Z2] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radiiKUKA(1), radiiKUKA(2), radiiKUKA(3));
% k = 1;
% m = 21;
% X2 = zeros(21,21*numj);
% Y2 = zeros(21,21*numj);
% Z2 = zeros(21,21*numj);
% 
% for j = 1:21:21*numj
%     [Z2(1:21,j:m),X2(1:21,j:m),Y2(1:21,j:m)] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radii(k,1), radii(k,2), radii(k,3));
%     m = m + 21;
% end
% X2 = X2(:);
% Y2 = Y2(:);
% Z2 = Z2(:);


% b = 1;
% n = 422;
% for i = 1:12
%     app.modelKUKAmodel.points{i} = [X2(b:n), Y2(b:n), Z2(b:n)];
%     warning off
%     app.modelKUKAmodel.faces{i} = delaunay(app.modelKUKAmodel.points{i});
%     warning on;
%     b = b + 422;
%     n = n + 422;
% end
% 
for i = 11
    app.modelKUKA.model.points{i} = [X2(:), Y2(:), Z2(:)];
    warning off
    app.modelKUKA.model.faces{i} = delaunay(app.modelKUKA.model.points{i});
    warning on;

end
EptsKUKA(1:441,1) = X2(:);
EptsKUKA(1:441,2) = Y2(:);
EptsKUKA(1:441,3) = Z2(:);


app.modelKUKA

person1=PlaceObject('personMaleConstructionEdited.ply',[0,2.5,0]);

stopbutt=PlaceObject('emergencyStopButtonEdited.ply',[-0.25,2.2,1.15]);
            gavins=PlaceObject('barrier1.5x0.2x1m.ply',[0,-2,0]);
number=PlaceObject('barrier1.5x0.2x1m.ply',[-1.5,-2,0]);
one=PlaceObject('barrier1.5x0.2x1m.ply',[1.5,-2,0]);

fan=PlaceObject('barrierRotated.ply',[2.25,-1.25,0]);
woohooo=PlaceObject('barrierRotated.ply',[2.25,0.25,0]);
barrier6=PlaceObject('barrierRotated.ply',[2.25,1.75,0]);

barrier7=PlaceObject('barrierRotated.ply',[-2.25,-1.25,0]);
barrier8=PlaceObject('barrierRotated.ply',[-2.25,0.25,0]);
barrier9=PlaceObject('barrierRotated.ply',[-2.25,1.75,0]);


barrier10=PlaceObject('barrier1.5x0.2x1m.ply',[-1.5,2.5,0]);
barrier11=PlaceObject('barrier1.5x0.2x1m.ply',[1.5,2.5,0]);

person2=PlaceObject('personMaleOld.ply',[-1.5,3,0]);
gavin=PlaceObject('personMaleCasual.ply',[-2.8,0,0]);
sheila=PlaceObject('personFemaleBusiness.ply',[-0.5,-2.5,0]);

fireEx=PlaceObject('fireExtinguisher.ply',[1.5,2.6,0]);


% id = 1; % NOTE: may need to change if multiple joysticks present
% 
% app.joy = vrjoystick(id);
% joy_info = caps(joy);

        end

        % Button pushed function: Button_L1
        function Button_L1Pushed(app, event)
            app.wackInput=1;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];

            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
              [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
            app.StatusWackBotLabel.Text="Awaiting Input";
            app.StatusShieldBotLabel.Text=["Awaiting Input"];              
            end
        end

        % Button pushed function: Button_L2
        function Button_L2Pushed(app, event)
            app.wackInput=2;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];
            end
        end

        % Button pushed function: Button_L3
        function Button_L3Pushed(app, event)
            app.wackInput=3;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];  

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_L4
        function Button_L4Pushed(app, event)
            app.wackInput=4;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];      

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_L5
        function Button_L5Pushed(app, event)
            app.wackInput=5;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];

            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];         

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_L6
        function Button_L6Pushed(app, event)
            app.wackInput=6;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_L7
        function Button_L7Pushed(app, event)
            app.wackInput=7;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];          

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_L8
        function Button_L8Pushed(app, event)
            app.wackInput=8;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];                
            end
        end

        % Button pushed function: Button_L9
        function Button_L9Pushed(app, event)
            app.wackInput=9;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.eStop=true;
            eStopFunc(app.modelUR3,app.modelKUKA,app.UR3q0,app.KUKAq0)
            
        end

        % Button pushed function: ResetWackBotButton
        function ResetWackBotButtonPushed(app, event)
            app.StatusWackBotLabel.Text=["Awaiting Input"]; 
            app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];

            app.wackBotScore=0;
            app.wackInput=0;
            app.WackBotScoreBoardLabel.Text=["WackBot Score: ", num2str(app.wackBotScore)];
            resetBots(app.modelUR3, 0,app.UR3q0, app.KUKAq0)
        end

        % Button pushed function: ResetShieldBotButton
        function ResetShieldBotButtonPushed(app, event)
            app.StatusShieldBotLabel.Text=["Awaiting Input"]; 
            app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];

            app.shieldBotScore=0;
            app.shieldInput=0;
            app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
            resetBots(0, app.modelKUKA,app.UR3q0, app.KUKAq0)
        end

        % Button pushed function: Button_R1
        function Button_R1Pushed(app, event)
            app.shieldInput=1;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];     

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R2
        function Button_R2Pushed(app, event)
            app.shieldInput=2;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R3
        function Button_R3Pushed(app, event)
            app.shieldInput=3;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R4
        function Button_R4Pushed(app, event)
            app.shieldInput=4;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];        

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R5
        function Button_R5Pushed(app, event)
            app.shieldInput=5;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];    

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R6
        function Button_R6Pushed(app, event)
            app.shieldInput=6;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];                
            end
        end

        % Button pushed function: Button_R7
        function Button_R7Pushed(app, event)
            app.shieldInput=7;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];    

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R8
        function Button_R8Pushed(app, event)
            app.shieldInput=8;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text="Awaiting Input";
                app.StatusShieldBotLabel.Text=["Awaiting Input"];        

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: Button_R9
        function Button_R9Pushed(app, event)
            app.shieldInput=9;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: ViewHitboxButton
        function ViewHitboxButtonPushed(app, event)
            qCurrent=app.modelUR3.model.getpos()
            app.modelUR3.model.plot3d(qCurrent);
%             alpha(0.1)
        end

        % Button pushed function: ViewHitboxButton_2
        function ViewHitboxButton_2Pushed(app, event)
            qCurrent=app.modelKUKA.model.getpos()
            app.modelKUKA.model.plot3d(qCurrent,'workspace', [-2 2 -2 2 0 2]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.figure1);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WackBotScoreBoardLabel = uilabel(app.GridLayout);
            app.WackBotScoreBoardLabel.Text = 'WackBotScoreBoard';
            app.ResetWackBotButton = uibutton(app.GridLayout, 'push');
            app.ResetWackBotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWackBotButtonPushed, true);
            app.ResetWackBotButton.Text = 'Reset WackBot';
            app.ResetShieldBotButton = uibutton(app.GridLayout, 'push');
            app.ResetShieldBotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetShieldBotButtonPushed, true);
            app.ResetShieldBotButton.Text = 'Reset ShieldBot';
            app.Button_L1 = uibutton(app.GridLayout, 'push');
            app.Button_L1.ButtonPushedFcn = createCallbackFcn(app, @Button_L1Pushed, true);
            app.Button_L1.Icon = fullfile(pathToMLAPP, 'MoleIcon1.PNG');
            app.Button_L1.Text = '';
            app.Button_L2 = uibutton(app.GridLayout, 'push');
            app.Button_L2.ButtonPushedFcn = createCallbackFcn(app, @Button_L2Pushed, true);
            app.Button_L2.Icon = fullfile(pathToMLAPP, 'MoleIcon2.PNG');
            app.Button_L2.Text = '';
            app.Button_L3 = uibutton(app.GridLayout, 'push');
            app.Button_L3.ButtonPushedFcn = createCallbackFcn(app, @Button_L3Pushed, true);
            app.Button_L3.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole3.png');
            app.Button_L3.Text = '';
            app.Button_R1 = uibutton(app.GridLayout, 'push');
            app.Button_R1.ButtonPushedFcn = createCallbackFcn(app, @Button_R1Pushed, true);
            app.Button_R1.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole1.png');
            app.Button_R1.Text = '';
            app.Button_R2 = uibutton(app.GridLayout, 'push');
            app.Button_R2.ButtonPushedFcn = createCallbackFcn(app, @Button_R2Pushed, true);
            app.Button_R2.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole2.png');
            app.Button_R2.Text = '';
            app.Button_R3 = uibutton(app.GridLayout, 'push');
            app.Button_R3.ButtonPushedFcn = createCallbackFcn(app, @Button_R3Pushed, true);
            app.Button_R3.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole3.png');
            app.Button_R3.Text = '';
            app.Button_L4 = uibutton(app.GridLayout, 'push');
            app.Button_L4.ButtonPushedFcn = createCallbackFcn(app, @Button_L4Pushed, true);
            app.Button_L4.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole4.png');
            app.Button_L4.Text = '';
            app.Button_L5 = uibutton(app.GridLayout, 'push');
            app.Button_L5.ButtonPushedFcn = createCallbackFcn(app, @Button_L5Pushed, true);
            app.Button_L5.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole5.png');
            app.Button_L5.Text = '';
            app.Button_L6 = uibutton(app.GridLayout, 'push');
            app.Button_L6.ButtonPushedFcn = createCallbackFcn(app, @Button_L6Pushed, true);
            app.Button_L6.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole6.png');
            app.Button_L6.Text = '';
            app.Button_R4 = uibutton(app.GridLayout, 'push');
            app.Button_R4.ButtonPushedFcn = createCallbackFcn(app, @Button_R4Pushed, true);
            app.Button_R4.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole4.png');
            app.Button_R4.Text = '';
            app.Button_R5 = uibutton(app.GridLayout, 'push');
            app.Button_R5.ButtonPushedFcn = createCallbackFcn(app, @Button_R5Pushed, true);
            app.Button_R5.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole5.png');
            app.Button_R5.Text = '';
            app.Button_R6 = uibutton(app.GridLayout, 'push');
            app.Button_R6.ButtonPushedFcn = createCallbackFcn(app, @Button_R6Pushed, true);
            app.Button_R6.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole6.png');
            app.Button_R6.Text = '';
            app.Button_L7 = uibutton(app.GridLayout, 'push');
            app.Button_L7.ButtonPushedFcn = createCallbackFcn(app, @Button_L7Pushed, true);
            app.Button_L7.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole7.png');
            app.Button_L7.Text = '';
            app.Button_L8 = uibutton(app.GridLayout, 'push');
            app.Button_L8.ButtonPushedFcn = createCallbackFcn(app, @Button_L8Pushed, true);
            app.Button_L8.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole8.png');
            app.Button_L8.Text = '';
            app.Button_L9 = uibutton(app.GridLayout, 'push');
            app.Button_L9.ButtonPushedFcn = createCallbackFcn(app, @Button_L9Pushed, true);
            app.Button_L9.Icon = fullfile(pathToMLAPP, 'Moles_IM', 'Mole9.png');
            app.Button_L9.Text = '';
            app.Button_R7 = uibutton(app.GridLayout, 'push');
            app.Button_R7.ButtonPushedFcn = createCallbackFcn(app, @Button_R7Pushed, true);
            app.Button_R7.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole7.png');
            app.Button_R7.Text = '';
            app.Button_R8 = uibutton(app.GridLayout, 'push');
            app.Button_R8.ButtonPushedFcn = createCallbackFcn(app, @Button_R8Pushed, true);
            app.Button_R8.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole8.png');
            app.Button_R8.Text = '';
            app.Button_R9 = uibutton(app.GridLayout, 'push');
            app.Button_R9.ButtonPushedFcn = createCallbackFcn(app, @Button_R9Pushed, true);
            app.Button_R9.Icon = fullfile(pathToMLAPP, 'Mole_IM_S', 'Mole9.png');
            app.Button_R9.Text = '';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ShieldBotScoreBoardLabel = uilabel(app.GridLayout);
            app.ShieldBotScoreBoardLabel.Text = {'ShieldBotScoreBoard'; ''};
            app.WackBotLabel = uilabel(app.GridLayout);
            app.WackBotLabel.Text = 'WackBot';
            app.ShieldBotLabel = uilabel(app.GridLayout);
            app.ShieldBotLabel.Text = 'ShieldBot';
            app.StatusWackBotLabel = uilabel(app.GridLayout);
            app.StatusWackBotLabel.Text = 'StatusWackBot';
            app.StatusShieldBotLabel = uilabel(app.GridLayout);
            app.StatusShieldBotLabel.Text = 'StatusShieldBot';
            app.UR3JointStateLabel = uilabel(app.GridLayout);
            app.UR3JointStateLabel.Text = 'UR3JointState';
            app.KUKAJointStateLabel = uilabel(app.GridLayout);
            app.KUKAJointStateLabel.Text = 'KUKAJointState';
            app.ViewHitboxButton = uibutton(app.GridLayout, 'push');
            app.ViewHitboxButton.ButtonPushedFcn = createCallbackFcn(app, @ViewHitboxButtonPushed, true);
            app.ViewHitboxButton.Text = 'View Hitbox';
            app.ViewHitboxButton_2 = uibutton(app.GridLayout, 'push');
            app.ViewHitboxButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewHitboxButton_2Pushed, true);
            app.ViewHitboxButton_2.Text = 'View Hitbox';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WackBotMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end