classdef WackBotTeach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        KAKUq8Slider         matlab.ui.control.Slider
        KAKUq8SliderLabel    matlab.ui.control.Label
        KAKUq7Slider         matlab.ui.control.Slider
        KAKUq7SliderLabel    matlab.ui.control.Label
        KAKUq6Slider         matlab.ui.control.Slider
        KAKUq6SliderLabel    matlab.ui.control.Label
        KAKUq5Slider         matlab.ui.control.Slider
        KAKUq5SliderLabel    matlab.ui.control.Label
        KAKUq4Slider         matlab.ui.control.Slider
        KAKUq4SliderLabel    matlab.ui.control.Label
        KAKUq3Slider         matlab.ui.control.Slider
        KAKUq3SliderLabel    matlab.ui.control.Label
        KAKUq2Slider         matlab.ui.control.Slider
        KAKUq2SliderLabel    matlab.ui.control.Label
        KAKUq1Slider         matlab.ui.control.Slider
        KAKUq1SliderLabel    matlab.ui.control.Label
        URq1Slider           matlab.ui.control.Slider
        URq1SliderLabel      matlab.ui.control.Label
        URq2Slider           matlab.ui.control.Slider
        URq2SliderLabel      matlab.ui.control.Label
        URq3Slider           matlab.ui.control.Slider
        URq3SliderLabel      matlab.ui.control.Label
        URq4Slider           matlab.ui.control.Slider
        URq4SliderLabel      matlab.ui.control.Label
        URq5Slider           matlab.ui.control.Slider
        URq5SliderLabel      matlab.ui.control.Label
        URq6Slider           matlab.ui.control.Slider
        URq6SliderLabel      matlab.ui.control.Label
        ViewHitboxButton_2   matlab.ui.control.Button
        ViewHitboxButton     matlab.ui.control.Button
        KUKAJointStateLabel  matlab.ui.control.Label
        UR3JointStateLabel   matlab.ui.control.Label
        STOPButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelUR3 % Where I will put the loaded UR5
        modelKUKA
        mole
        eStop
        warningdialog
        wackBotScore
        shieldBotScore
        wackInput
        shieldInput
        winner
        joy

        qz = [0     0       0       0               0       0   0];
%         KUKAq0 =[-0.01 0 0	pi/3   0	    pi/4	    0	    1.3090   pi 0 0 0]
        KUKAq0 =[-0.5854    0.0009    0.0727    0.7652-0.3491    0.2359    2.1368+0.3491   -3.7208   -0.3004    0.7854         0         0   0]
        UR3q0 = [0.2427	4.3808	4.6426	-1.194985	-4.71235274267090	-1.30105403435293	5.95508023803729e-06	5.95508307285278e-06	-0.0270717610752961]; % Description
    end
    
    methods (Access = private)
       

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = gca;
            global reset
            reset=false;

            app.wackBotScore=0;
            app.shieldBotScore=0;

            app.wackInput=0;
            app.shieldInput=0;

%             app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];
%             app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
% 
% %             app.StatusLabel.Text="Status:";
% 
%             app.StatusWackBotLabel.Text="Awaiting Input";
%             app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
% 
%             app.StatusShieldBotLabel.Text="Awaiting Input";
%             app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];


            hold on
            axis([-2 2 -2 2 -0.01 2 ]);
%             view(3)
            campos([-0.0045  -10.5324    6.3959])
            camtarget([0.1088   -0.0471    0.7224])
            
%             camva(3.5)
            % Make, save and plot some robots            
            app.modelUR3 = ModifiedUR3;
            app.modelUR3.model.base = transl([-0.6,0,0.574]);
            app.modelUR3.model.animate(app.UR3q0);
            drawnow();

            app.modelKUKA = KUKA2(transl([1.1,0,0.5]));
%             app.modelKUKA = KUKA;
%             app.modelKUKA.model.base = transl([0.8,0,0.5]);
            app.modelKUKA.model.animate(app.KUKAq0);
            drawnow();
            
            app.eStop=false;
%             app.reset=false;
          

            app.URq1Slider.Value=rad2deg(app.UR3q0(1));
            app.URq2Slider.Value=rad2deg(app.UR3q0(2));
            app.URq3Slider.Value=rad2deg(app.UR3q0(3));
            app.URq4Slider.Value=rad2deg(app.UR3q0(4));
            app.URq5Slider.Value=rad2deg(app.UR3q0(5));
            app.URq6Slider.Value=rad2deg(app.UR3q0(6));


            app.KAKUq1Slider.Value=(app.KUKAq0(1));
            app.KAKUq2Slider.Value=rad2deg(app.KUKAq0(3));
            app.KAKUq3Slider.Value=rad2deg(app.KUKAq0(4));
            app.KAKUq4Slider.Value=rad2deg(app.KUKAq0(5));
            app.KAKUq5Slider.Value=rad2deg(app.KUKAq0(6));
            app.KAKUq6Slider.Value=rad2deg(app.KUKAq0(7));
            app.KAKUq7Slider.Value=rad2deg(app.KUKAq0(8));
            app.KAKUq8Slider.Value=rad2deg(app.KUKAq0(9));
      

            surf([-4,-4;4,4] ...
            ,[-4,4;-4,4] ...
            ,[0.01,0.01;0.01,0.01] ...
            ,'CData',imread('floor.jpg') ...
            ,'FaceColor','texturemap');
            
            PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0.25,0,0]);
            
            % PlaceObject('Moles.ply', [0,0,0.5]);
            PlaceObject('Base2.ply', [0,0,0.5]);
            
            moleID(1,1)=PlaceObject('MoleNew.ply', [-0.06, 0.06,    0.5]);
            moleID(2,1)=PlaceObject('MoleNew.ply', [0,    0.06,    0.515]);
            moleID(3,1)=PlaceObject('MoleNew.ply', [0.06,  0.06,    0.5]);
            
            moleID(4,1)=PlaceObject('MoleNew.ply', [-0.06, 0,  0.5]);
            moleID(5,1)=PlaceObject('MoleNew.ply', [0,    0,  0.515]);
            moleID(6,1)=PlaceObject('MoleNew.ply', [0.06,  0,  0.5]);
            
            
            moleID(7,1)=PlaceObject('MoleNew.ply', [-0.06, -0.06,  0.515]);
            moleID(8,1)=PlaceObject('MoleNew.ply', [0,    -0.06,  0.5]);
            moleID(9,1)=PlaceObject('MoleNew.ply', [0.06,  -0.06,  0.5]);

            height=(0.574);
            molePos=[-0.06    0.06     height
                0      0.06     height
                0.06    0.06     height
                -0.06     0       height
                0       0       height
                0.06     0       height
                -0.06   -0.06     height
                0     -0.06      height
                0.06   -0.06      height];

            Status=[0;1;0;0;1;0;1;0;0];
            app.mole=table(moleID,Status,molePos);


% 
%             r = ModifiedUR3;

centerPoint = [0 0 0];
% radii = [0.328,0.091,0.123];
radii = [0.128 0.163 0.261;
            0.328,0.091,0.123;
            0.283,0.075,0.971;
            0.0644,0.0768,0.0877;
            0.0644,0.0768,0.0877;
            0.063,0.0696,0.0398;
            0.0614,0.0923,0.126;
            0.197,0.045,0.1
            0,0,0];

[X,Y,Z] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radii(1), radii(2), radii(3));
k = 1;
m = 21;
X = zeros(21,168);
Y = zeros(21,168);
Z = zeros(21,168);

for j = 1:21:168
    [X(1:21,j:m),Y(1:21,j:m),Z(1:21,j:m)] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radii(k,1), radii(k,2), radii(k,3));
    m = m + 21;
end
X = X(:);
Y = Y(:);
Z = Z(:);


b = 1;
n = 422;
for i = 1:8
    app.modelUR3.model.points{i} = [X(b:n), Y(b:n), Z(b:n)];
    warning off
    app.modelUR3.model.faces{i} = delaunay(app.modelUR3.model.points{i});
    warning on;
    b = b + 422;
    n = n + 422;
end

% 
% 
% app.modelUR3.model.plot3d([app.UR3q0]);
% % alpha(0.1)
% axis equal
% camlight


centerPoint = [0 0 0];
radiiKUKA = [ 0.20799, 0.046, 0.058 ];
% radii = [0.5,0.216,1.12;
%             0.217005,0.207952,0.157513;
%             0.135983,0.250555,0.182625;
%             0.136013,0.182614,0.284818;
%             0.136095,0.251215,0.182625;
%             0.136016,0.182597,0.285527;
%             0.135971, 0.234071, 0.167121 ;
%             0.132334, 0.129053,  0.167061;
%             0.104072, 0.104033, 0.030367;
%             0.061356, 0.092336, 0.125051;
%             0.20799, 0.046, 0.058 
%             0.01, 0.01, 0.01];

[X2,Y2,Z2] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radiiKUKA(1), radiiKUKA(2), radiiKUKA(3));
% k = 1;
% m = 21;
% X2 = zeros(21,21*numj);
% Y2 = zeros(21,21*numj);
% Z2 = zeros(21,21*numj);
% 
% for j = 1:21:21*numj
%     [Z2(1:21,j:m),X2(1:21,j:m),Y2(1:21,j:m)] = ellipsoid(centerPoint(1), centerPoint(2), centerPoint(3), radii(k,1), radii(k,2), radii(k,3));
%     m = m + 21;
% end
% X2 = X2(:);
% Y2 = Y2(:);
% Z2 = Z2(:);


% b = 1;
% n = 422;
% for i = 1:12
%     app.modelKUKAmodel.points{i} = [X2(b:n), Y2(b:n), Z2(b:n)];
%     warning off
%     app.modelKUKAmodel.faces{i} = delaunay(app.modelKUKAmodel.points{i});
%     warning on;
%     b = b + 422;
%     n = n + 422;
% end
% 
for i = 11
    app.modelKUKA.model.points{i} = [X2(:), Y2(:), Z2(:)];
    warning off
    app.modelKUKA.model.faces{i} = delaunay(app.modelKUKA.model.points{i});
    warning on;

end
EptsKUKA(1:441,1) = X2(:);
EptsKUKA(1:441,2) = Y2(:);
EptsKUKA(1:441,3) = Z2(:);


app.modelKUKA

person1=PlaceObject('personMaleConstructionEdited.ply',[0,2.5,0]);

stopbutt=PlaceObject('emergencyStopButtonEdited.ply',[-0.25,2.2,1.15]);
            gavins=PlaceObject('barrier1.5x0.2x1m.ply',[0,-2,0]);
number=PlaceObject('barrier1.5x0.2x1m.ply',[-1.5,-2,0]);
one=PlaceObject('barrier1.5x0.2x1m.ply',[1.5,-2,0]);

fan=PlaceObject('barrierRotated.ply',[2.25,-1.25,0]);
woohooo=PlaceObject('barrierRotated.ply',[2.25,0.25,0]);
barrier6=PlaceObject('barrierRotated.ply',[2.25,1.75,0]);

barrier7=PlaceObject('barrierRotated.ply',[-2.25,-1.25,0]);
barrier8=PlaceObject('barrierRotated.ply',[-2.25,0.25,0]);
barrier9=PlaceObject('barrierRotated.ply',[-2.25,1.75,0]);


barrier10=PlaceObject('barrier1.5x0.2x1m.ply',[-1.5,2.5,0]);
barrier11=PlaceObject('barrier1.5x0.2x1m.ply',[1.5,2.5,0]);

person2=PlaceObject('personMaleOld.ply',[-1.5,3,0]);
gavin=PlaceObject('personMaleCasual.ply',[-2.8,0,0]);
sheila=PlaceObject('personFemaleBusiness.ply',[-0.5,-2.5,0]);

fireEx=PlaceObject('fireExtinguisher.ply',[1.5,2.6,0]);


% id = 1; % NOTE: may need to change if multiple joysticks present
% 
% app.joy = vrjoystick(id);
% joy_info = caps(joy);

        end

        % Callback function
        function Button_L1Pushed(app, event)
            app.wackInput=1;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];

            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
              [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
            app.StatusWackBotLabel.Text="Awaiting Input";
            app.StatusShieldBotLabel.Text=["Awaiting Input"];              
            end
        end

        % Callback function
        function Button_L2Pushed(app, event)
            app.wackInput=2;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];
            end
        end

        % Callback function
        function Button_L3Pushed(app, event)
            app.wackInput=3;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];  

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_L4Pushed(app, event)
            app.wackInput=4;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput~=0)&&(app.shieldInput~=0)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];      

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_L5Pushed(app, event)
            app.wackInput=5;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];

            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];         

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_L6Pushed(app, event)
            app.wackInput=6;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_L7Pushed(app, event)
            app.wackInput=7;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];          

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_L8Pushed(app, event)
            app.wackInput=8;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];                
            end
        end

        % Callback function
        function Button_L9Pushed(app, event)
            app.wackInput=9;
            app.StatusWackBotLabel.Text=["Selected Mole: ", app.wackInput];
            app.StatusWackBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.eStop=true;
            eStopFunc(app.modelUR3,app.modelKUKA,app.UR3q0,app.KUKAq0)
            
        end

        % Callback function
        function ResetWackBotButtonPushed(app, event)
            app.StatusWackBotLabel.Text=["Awaiting Input"]; 
            app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];

            app.wackBotScore=0;
            app.wackInput=0;
            app.WackBotScoreBoardLabel.Text=["WackBot Score: ", num2str(app.wackBotScore)];
            resetBots(app.modelUR3, 0,app.UR3q0, app.KUKAq0)
        end

        % Callback function
        function ResetShieldBotButtonPushed(app, event)
            app.StatusShieldBotLabel.Text=["Awaiting Input"]; 
            app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];

            app.shieldBotScore=0;
            app.shieldInput=0;
            app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
            resetBots(0, app.modelKUKA,app.UR3q0, app.KUKAq0)
        end

        % Callback function
        function Button_R1Pushed(app, event)
            app.shieldInput=1;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];     

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R2Pushed(app, event)
            app.shieldInput=2;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R3Pushed(app, event)
            app.shieldInput=3;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R4Pushed(app, event)
            app.shieldInput=4;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];        

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R5Pushed(app, event)
            app.shieldInput=5;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text="Awaiting Input";
                app.StatusShieldBotLabel.Text=["Awaiting Input"];    

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R6Pushed(app, event)
            app.shieldInput=6;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];                
            end
        end

        % Callback function
        function Button_R7Pushed(app, event)
            app.shieldInput=7;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];    

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R8Pushed(app, event)
            app.shieldInput=8;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text="Awaiting Input";
                app.StatusShieldBotLabel.Text=["Awaiting Input"];        

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Callback function
        function Button_R9Pushed(app, event)
            app.shieldInput=9;
            app.StatusShieldBotLabel.Text=["Selected Mole: ", app.shieldInput];
            app.StatusShieldBotLabel.BackgroundColor=[0.77,1.00,0.70];
            pause(0.1);
            if (app.wackInput&&app.shieldInput)
                [app.wackInput, app.shieldInput,app.winner,app.mole]=botMove(app.mole, app.modelUR3, app.modelKUKA, app.wackInput, app.shieldInput,app.UR3q0,app.KUKAq0,app.UR3JointStateLabel,app.KUKAJointStateLabel);
                switch app.winner
                    case 1
                        app.wackBotScore=app.wackBotScore+1;            
                        app.WackBotScoreBoardLabel.Text=["Wackbot Score: ", num2str(app.wackBotScore)];

                    case 2
                        app.shieldBotScore=app.shieldBotScore+2;
                        app.ShieldBotScoreBoardLabel.Text=["ShieldBot Score: ", num2str(app.shieldBotScore)];
                end
                app.StatusWackBotLabel.Text=["Awaiting Input"];
                app.StatusShieldBotLabel.Text=["Awaiting Input"];   

                app.StatusWackBotLabel.BackgroundColor=[1 1 0.66];
                app.StatusShieldBotLabel.BackgroundColor=[1 1 0.66];                
            end
        end

        % Button pushed function: ViewHitboxButton
        function ViewHitboxButtonPushed(app, event)
            qCurrent=app.modelUR3.model.getpos()
            app.modelUR3.model.plot3d(qCurrent);
%             alpha(0.1)
        end

        % Button pushed function: ViewHitboxButton_2
        function ViewHitboxButton_2Pushed(app, event)
            qCurrent=app.modelKUKA.model.getpos();
            app.modelKUKA.model.plot3d(qCurrent,'workspace', [-2 2 -2 2 0 2]);
        end

        % Value changed function: URq1Slider
        function URq1SliderValueChanged(app, event)
            value = app.URq1Slider.Value;
            joint=1;
            Teach(value,joint,app.modelUR3);

        end

        % Value changed function: URq2Slider
        function URq2SliderValueChanged(app, event)
            value = app.URq2Slider.Value;
            joint=2;
            Teach(value,joint,app.modelUR3);
        end

        % Value changed function: URq3Slider
        function URq3SliderValueChanged(app, event)
            value = app.URq3Slider.Value;
            joint=3;
            Teach(value,joint,app.modelUR3);
        end

        % Value changed function: URq4Slider
        function URq4SliderValueChanged(app, event)
            value = app.URq4Slider.Value;
            joint=4;
            Teach(value,joint,app.modelUR3);
        end

        % Value changed function: URq5Slider
        function URq5SliderValueChanged(app, event)
            value = app.URq5Slider.Value;
            joint=5;
            Teach(value,joint,app.modelUR3);
        end

        % Value changed function: URq6Slider
        function URq6SliderValueChanged(app, event)
            value = app.URq6Slider.Value;
            joint=6;
            Teach(value,joint,app.modelUR3);
        end

        % Value changed function: KAKUq1Slider
        function KAKUq1SliderValueChanged(app, event)
            q = app.KAKUq1Slider.Value;

        joint=1
            robot=app.modelKUKA;
        qCurrent=robot.model.getpos();
        qNew=qCurrent;
        qNew(1,joint)=q;
        % robot.model.animate(qNew)
        
        
        qmatrix = jtraj(qCurrent,qNew,5);          
        for robotStepIndex = 1:size(qmatrix,1)
            robot.model.animate(qmatrix(robotStepIndex,:));
            drawnow;
        end
        end

        % Value changed function: KAKUq2Slider
        function KAKUq2SliderValueChanged(app, event)
            value = app.KAKUq2Slider.Value;
            joint=3;
            Teach(value,joint,app.modelKUKA);

        end

        % Value changed function: KAKUq3Slider
        function KAKUq3SliderValueChanged(app, event)
            value = app.KAKUq3Slider.Value;
            joint=4;
            Teach(value,joint,app.modelKUKA);

        end

        % Value changed function: KAKUq4Slider
        function KAKUq4SliderValueChanged(app, event)
            value = app.KAKUq4Slider.Value;
            joint=5;
            Teach(value,joint,app.modelKUKA);
        end

        % Value changed function: KAKUq5Slider
        function KAKUq5SliderValueChanged(app, event)
            value = app.KAKUq5Slider.Value;
            joint=6;
            Teach(value,joint,app.modelKUKA);            
        end

        % Value changed function: KAKUq6Slider
        function KAKUq6SliderValueChanged(app, event)
            value = app.KAKUq6Slider.Value;
            joint=7;
            Teach(value,joint,app.modelKUKA);
        end

        % Value changed function: KAKUq7Slider
        function KAKUq7SliderValueChanged(app, event)
            value = app.KAKUq7Slider.Value;
            joint=8;
            Teach(value,joint,app.modelKUKA);
            
        end

        % Value changed function: KAKUq8Slider
        function KAKUq8SliderValueChanged(app, event)
            value = app.KAKUq8Slider.Value;
            joint=9;
            Teach(value,joint,app.modelKUKA);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.figure1);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.UR3JointStateLabel = uilabel(app.GridLayout);
            app.UR3JointStateLabel.Text = 'UR3JointState';
            app.KUKAJointStateLabel = uilabel(app.GridLayout);
            app.KUKAJointStateLabel.Text = 'KUKAJointState';
            app.ViewHitboxButton = uibutton(app.GridLayout, 'push');
            app.ViewHitboxButton.ButtonPushedFcn = createCallbackFcn(app, @ViewHitboxButtonPushed, true);
            app.ViewHitboxButton.Text = 'View Hitbox';
            app.ViewHitboxButton_2 = uibutton(app.GridLayout, 'push');
            app.ViewHitboxButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewHitboxButton_2Pushed, true);
            app.ViewHitboxButton_2.Text = 'View Hitbox';
            app.URq6SliderLabel = uilabel(app.GridLayout);
            app.URq6SliderLabel.Text = 'URq6';
            app.URq6Slider = uislider(app.GridLayout);
            app.URq6Slider.Limits = [-120 -60];
            app.URq6Slider.ValueChangedFcn = createCallbackFcn(app, @URq6SliderValueChanged, true);
            app.URq6Slider.Value = -60;
            app.URq5SliderLabel = uilabel(app.GridLayout);
            app.URq5SliderLabel.Text = 'URq5';
            app.URq5Slider = uislider(app.GridLayout);
            app.URq5Slider.Limits = [-360 360];
            app.URq5Slider.ValueChangedFcn = createCallbackFcn(app, @URq5SliderValueChanged, true);
            app.URq5Slider.Value = -45;
            app.URq4SliderLabel = uilabel(app.GridLayout);
            app.URq4SliderLabel.Text = 'URq4';
            app.URq4Slider = uislider(app.GridLayout);
            app.URq4Slider.Limits = [-125 -45];
            app.URq4Slider.ValueChangedFcn = createCallbackFcn(app, @URq4SliderValueChanged, true);
            app.URq4Slider.Value = -45;
            app.URq3SliderLabel = uilabel(app.GridLayout);
            app.URq3SliderLabel.Text = 'URq3';
            app.URq3Slider = uislider(app.GridLayout);
            app.URq3Slider.Limits = [-360 360];
            app.URq3Slider.ValueChangedFcn = createCallbackFcn(app, @URq3SliderValueChanged, true);
            app.URq2SliderLabel = uilabel(app.GridLayout);
            app.URq2SliderLabel.Text = 'URq2';
            app.URq2Slider = uislider(app.GridLayout);
            app.URq2Slider.Limits = [-360 360];
            app.URq2Slider.ValueChangedFcn = createCallbackFcn(app, @URq2SliderValueChanged, true);
            app.URq1SliderLabel = uilabel(app.GridLayout);
            app.URq1SliderLabel.Text = 'URq1';
            app.URq1Slider = uislider(app.GridLayout);
            app.URq1Slider.Limits = [-160 160];
            app.URq1Slider.ValueChangedFcn = createCallbackFcn(app, @URq1SliderValueChanged, true);
            app.KAKUq1SliderLabel = uilabel(app.GridLayout);
            app.KAKUq1SliderLabel.Text = 'KAKUq1';
            app.KAKUq1Slider = uislider(app.GridLayout);
            app.KAKUq1Slider.Limits = [-0.75 -0.01];
            app.KAKUq1Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq1SliderValueChanged, true);
            app.KAKUq1Slider.Value = -0.01;
            app.KAKUq2SliderLabel = uilabel(app.GridLayout);
            app.KAKUq2SliderLabel.Text = 'KAKUq2';
            app.KAKUq2Slider = uislider(app.GridLayout);
            app.KAKUq2Slider.Limits = [-60 60];
            app.KAKUq2Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq2SliderValueChanged, true);
            app.KAKUq3SliderLabel = uilabel(app.GridLayout);
            app.KAKUq3SliderLabel.Text = 'KAKUq3';
            app.KAKUq3Slider = uislider(app.GridLayout);
            app.KAKUq3Slider.Limits = [-90 180];
            app.KAKUq3Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq3SliderValueChanged, true);
            app.KAKUq4SliderLabel = uilabel(app.GridLayout);
            app.KAKUq4SliderLabel.Text = 'KAKUq4';
            app.KAKUq4Slider = uislider(app.GridLayout);
            app.KAKUq4Slider.Limits = [-30 30];
            app.KAKUq4Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq4SliderValueChanged, true);
            app.KAKUq5SliderLabel = uilabel(app.GridLayout);
            app.KAKUq5SliderLabel.Text = 'KAKUq5';
            app.KAKUq5Slider = uislider(app.GridLayout);
            app.KAKUq5Slider.Limits = [0 150];
            app.KAKUq5Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq5SliderValueChanged, true);
            app.KAKUq6SliderLabel = uilabel(app.GridLayout);
            app.KAKUq6SliderLabel.Text = 'KAKUq6';
            app.KAKUq6Slider = uislider(app.GridLayout);
            app.KAKUq6Slider.Limits = [-360 360];
            app.KAKUq6Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq6SliderValueChanged, true);
            app.KAKUq7SliderLabel = uilabel(app.GridLayout);
            app.KAKUq7SliderLabel.Text = 'KAKUq7';
            app.KAKUq7Slider = uislider(app.GridLayout);
            app.KAKUq7Slider.Limits = [-90 180];
            app.KAKUq7Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq7SliderValueChanged, true);
            app.KAKUq7Slider.Value = 90;
            app.KAKUq8SliderLabel = uilabel(app.GridLayout);
            app.KAKUq8SliderLabel.Text = 'KAKUq8';
            app.KAKUq8Slider = uislider(app.GridLayout);
            app.KAKUq8Slider.Limits = [45 270];
            app.KAKUq8Slider.ValueChangedFcn = createCallbackFcn(app, @KAKUq8SliderValueChanged, true);
            app.KAKUq8Slider.Value = 90;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WackBotTeach

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end